classdef SubcellularTrackingWithFLIMCharacterisationV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FramesSlider                    matlab.ui.control.Slider
        LoadDataPanel                   matlab.ui.container.Panel
        LoadTIFFLIMDataFilesButton      matlab.ui.control.Button
        SaveCh1IntensityDataForTrackingButton  matlab.ui.control.Button
        LoadDiatrackOutputFileFromCh1TrackingButton  matlab.ui.control.Button
        StringForOutputFileNameEditFieldLabel  matlab.ui.control.Label
        StringForOutputFileNameEditField  matlab.ui.control.EditField
        FrametimesecondsEditFieldLabel  matlab.ui.control.Label
        FrametimesecondsEditField       matlab.ui.control.NumericEditField
        PixelSizemicrometerEditFieldLabel  matlab.ui.control.Label
        PixelSizemicrometerEditField    matlab.ui.control.NumericEditField
        LBXSliderLabel                  matlab.ui.control.Label
        LBXSlider                       matlab.ui.control.Slider
        UBXSliderLabel                  matlab.ui.control.Label
        UBXSlider                       matlab.ui.control.Slider
        LBYSliderLabel                  matlab.ui.control.Label
        LBYSlider                       matlab.ui.control.Slider
        UBYSliderLabel                  matlab.ui.control.Label
        UBYSlider                       matlab.ui.control.Slider
        FilterShortTrajectoriesAndExtractLifetimesPanel  matlab.ui.container.Panel
        MinimumTrajectoryLengthFramesEditFieldLabel  matlab.ui.control.Label
        MinimumTrajectoryLengthFramesEditField  matlab.ui.control.NumericEditField
        RadiusOfNeighborhoodForLifetimeExtractionpixelsEditFieldLabel  matlab.ui.control.Label
        RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField  matlab.ui.control.NumericEditField
        IDValidTrajectoriesButton       matlab.ui.control.Button
        ExtractLifetimesAlongTrajectoriesAndSaveDataButton  matlab.ui.control.Button
        GenerateFLIMTrajectoriesMoviePanel  matlab.ui.container.Panel
        ApplyLoGFiltertoBackgroundImageButton  matlab.ui.control.StateButton
        LoGFilterSigmaEditFieldLabel    matlab.ui.control.Label
        LoGFilterSigmaEditField         matlab.ui.control.NumericEditField
        WhatMovieTypeToExportDropDownLabel  matlab.ui.control.Label
        WhatMovieTypeToExportDropDown   matlab.ui.control.DropDown
        WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel  matlab.ui.control.Label
        WhatFrameRateDoYouWantToSaveVideofpsEditField  matlab.ui.control.NumericEditField
        ExportMovieButton               matlab.ui.control.Button
        ExportFirst15FramesTestButton   matlab.ui.control.Button
        IDGoodToDisplayTrajectoriesEditFieldLabel  matlab.ui.control.Label
        IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField  matlab.ui.control.EditField
        UseFilteredLifetimeValuesButton  matlab.ui.control.StateButton
        CropAreaWithinImageToDefineAPartofFOVtoOutputButton  matlab.ui.control.StateButton
        TakeaLogOfBackgroundIntensityCh1ImageButton  matlab.ui.control.StateButton
        ExploreAndFilterFLIMTracesPanel  matlab.ui.container.Panel
        ShowTrajectoriesWithIDButton    matlab.ui.control.Button
        WhichTrajectoryToDisplayEditFieldLabel  matlab.ui.control.Label
        WhichTrajectoryToDisplayEditField  matlab.ui.control.NumericEditField
        DisplayDropDownLabel            matlab.ui.control.Label
        DisplayDropDown                 matlab.ui.control.DropDown
        MovingWindowSizeEditFieldLabel  matlab.ui.control.Label
        MovingWindowSizeEditField       matlab.ui.control.NumericEditField
        FilterifneededanddisplayFLIMtracevstimeButton  matlab.ui.control.Button
        SaveFLIMFilteredTracesButton    matlab.ui.control.Button
        ConfidenceRangeLifetimeEditFieldLabel  matlab.ui.control.Label
        ConfidenceRangeLifetimeEditField  matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        DefineCellContourandCentrePanel  matlab.ui.container.Panel
        MorphologicalOperationsonBinaryDropDown  matlab.ui.control.DropDown
        PerformMorphologicalOperationsButton  matlab.ui.control.Button
        ImageToUsetoDefineCellShapeDropDownLabel  matlab.ui.control.Label
        ImageToUsetoDefineCellShapeDropDown  matlab.ui.control.DropDown
        ThresholdCellShapeSliderLabel   matlab.ui.control.Label
        ThresholdCellShapeSlider        matlab.ui.control.Slider
        ExtractCellContourandCenterCoordinatesButton  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        path
        fname
        ch1
        ch2
        listfiles
        slider
        xtraj
        ytraj
        timePart
        trajLength
        mintrajsize
        indgoodtraj
        radiusToUse
        AveIntCh2
        AveIntCh2b
        AveIntCh3
        CropArea
        LoGSig
        movFPS
        ch1tooutput
        back
        rect
        xb
        yb
        addtoname
        logged
        filt
        IndGoodToPlot
        movType
        timeFrame
        pixelsize
        allLifetimes
        bigmycolors
        DispTraj
        MovAveFilt
        MovAveFiltSize
        UseFilteredTraj
        DeltaFlimThresh
        IndGoodRange
        imToShape
        useToShape
        sliderShape
        binaryShape
        MorphChoice
        binary2
        xc
        yc
        boundary
        RangeDecision
        rangeY
        addtofile
        LBX
        UBX
        LBY
        UBY
        data3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTIFFLIMDataFilesButton
        function LoadTIFFLIMDataFilesButtonPushed(app, event)
            %filterspec = {'*.tiff;*.TIF;*.tif','Select all the TIF files that are part of FLIM data sequence'};
            [app.path] = uigetdir('Please select directory containing your TIF files from FLIM experiments');
            app.path=[app.path '\'];
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(app.path))
                list=dir([app.path '*.tif']);
                app.listfiles=natsortfiles({list.name});
                fname = [app.path 'FLIMdata'];
                app.fname=fname;
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=1:length(app.listfiles)
                    app.ch1(:,:,i)=imread([app.path app.listfiles{i}],1);
                    app.ch2(:,:,i)=imread([app.path app.listfiles{i}],2);
                    ph.XData = [0 i/length(app.listfiles) i/length(app.listfiles) 0];
                    th.String = sprintf('%.0f%%',round(i/length(app.listfiles)*100));
                    drawnow %update graphics
                end
            end
            app.FramesSlider.Limits = [1 size(app.ch1,3)];
            app.slider=1;
            app.radiusToUse=3;
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.Value=3;
            app.mintrajsize=10;
            app.MinimumTrajectoryLengthFramesEditField.Value=10;
            app.AveIntCh2=[];
            app.xtraj=[];
            app.ytraj=[];
            app.timePart=[];
            app.LoGSig=1.5;
            app.movFPS=5;
            app.movType='mp4'
            app.IndGoodToPlot='1 3 5 7 10:5:30';
            app.IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField.Value='1 3 5 7 10:5:30';
            app.allLifetimes=[];
            app.timeFrame=0.5;
            app.FrametimesecondsEditField.Value=0.5;
            app.pixelsize=0.1;
            app.PixelSizemicrometerEditField.Value=0.1;
            app.DispTraj=1;
            app.MovAveFiltSize=3;
            app.MovAveFilt='movmedian'
            app.useToShape='Temporal Average';
            app.MorphChoice='clean';
            app.addtofile='gorequal';
            app.CropAreaWithinImageToDefineAPartofFOVtoOutputButton.Value=0;
            app.ApplyLoGFiltertoBackgroundImageButton.Value=0;
            app.TakeaLogOfBackgroundIntensityCh1ImageButton.Value=0;
            app.WhatMovieTypeToExportDropDown.Value='mp4';
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField.Value=5;
            app.LoGFilterSigmaEditField.Value=3;
            %app.WhichTrajectoryToDisplayEditField.Value='Raw FLIM trace';
            app.MovingWindowSizeEditField.Value=3;
            app.UseFilteredTraj=0;
            app.rangeY=[];
            app.RangeDecision='>=';
            % predefine some stuff
            app.ch1tooutput=app.ch1;
            app.back=app.ch1;
            app.addtoname='nocrop';
            app.xb(1)=1;
            app.xb(2)=size(app.ch1,2);
            app.xb(3)=size(app.ch1,2);
            app.xb(4)=1;
            app.yb(1)=1;
            app.yb(2)=1;
            app.yb(3)=size(app.ch1,1);
            app.yb(4)=size(app.ch1,1);
            app.rect(1:2)=0;
            app.rect(3)=size(app.ch1,2);
            app.rect(4)=size(app.ch1,1);
            app.IndGoodRange=[];
            app.DeltaFlimThresh=0.3;
            app.LBXSlider.Limits = [1 size(app.ch1,2)];
            app.LBXSlider.MajorTicks = [1:ceil(size(app.ch1,2)/10):size(app.ch1,2)];
            app.LBXSlider.Value=1;
            app.UBXSlider.Limits = [1 size(app.ch1,2)];
            app.UBXSlider.MajorTicks = [1:ceil(size(app.ch1,2)/10):size(app.ch1,2)];
            app.UBXSlider.Value=size(app.ch1,2);
            app.LBYSlider.Limits = [1 size(app.ch1,1)];
            app.LBYSlider.MajorTicks = [1:ceil(size(app.ch1,1)/10):size(app.ch1,1)];
            app.LBYSlider.Value=1;
            app.UBYSlider.Limits = [1 size(app.ch1,1)];
            app.UBYSlider.MajorTicks = [1:ceil(size(app.ch1,1)/10):size(app.ch1,1)];
            app.UBYSlider.Value=size(app.ch1,1);
            app.LBX=1;
            app.UBX=size(app.ch1,2);
            app.LBY=1;
            app.UBY=size(app.ch1,1);
            app.data3=[];
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.ch1(:,:,app.slider));
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            hold(app.UIAxes,"on");
            colormap(app.UIAxes,'gray')
            text(app.UIAxes,size(app.ch1,2)-30,size(app.ch1,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
        end

        % Value changing function: LBXSlider
        function LBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBX=changingValue;
            app.data3=[];
            app.data3=squeeze(app.ch1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.data3(:,:,app.slider));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.data3,2)-20,size(app.data3,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.data3,2)])
            ylim(app.UIAxes,[1 size(app.data3,1)])
        end

        % Value changed function: LBXSlider
        function LBXSliderValueChanged(app, event)
            value = app.LBXSlider.Value;
            app.LBX=value;
        end

        % Value changing function: UBXSlider
        function UBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBX=changingValue;
            app.data3=[];
            app.data3=squeeze(app.ch1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.data3(:,:,app.slider));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.data3,2)-20,size(app.data3,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.data3,2)])
            ylim(app.UIAxes,[1 size(app.data3,1)])
        end

        % Value changed function: UBXSlider
        function UBXSliderValueChanged(app, event)
            value = app.UBXSlider.Value;
            app.UBX=value;
        end

        % Value changing function: LBYSlider
        function LBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBY=changingValue;
            app.data3=[];
            app.data3=squeeze(app.ch1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.data3(:,:,app.slider));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.data3,2)-20,size(app.data3,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.data3,2)])
            ylim(app.UIAxes,[1 size(app.data3,1)])
        end

        % Value changed function: LBYSlider
        function LBYSliderValueChanged(app, event)
            value = app.LBYSlider.Value;
            app.LBY=value;
        end

        % Value changing function: UBYSlider
        function UBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBY=changingValue;
            app.data3=[];
            app.data3=squeeze(app.ch1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.data3(:,:,app.slider));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.data3,2)-20,size(app.data3,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.data3,2)])
            ylim(app.UIAxes,[1 size(app.data3,1)])
        end

        % Value changed function: UBYSlider
        function UBYSliderValueChanged(app, event)
            value = app.UBYSlider.Value;
            app.UBY=value;
        end

        % Value changed function: FrametimesecondsEditField
        function FrametimesecondsEditFieldValueChanged(app, event)
            value = app.FrametimesecondsEditField.Value;
            app.timeFrame=value;
        end

        % Value changed function: PixelSizemicrometerEditField
        function PixelSizemicrometerEditFieldValueChanged(app, event)
            value = app.PixelSizemicrometerEditField.Value;
            app.pixelsize=value;
        end

        % Value changed function: StringForOutputFileNameEditField
        function StringForOutputFileNameEditFieldValueChanged(app, event)
            value = app.StringForOutputFileNameEditField.Value;
            fname = [app.path value];
            app.fname=fname;
        end

        % Button pushed function: 
        % SaveCh1IntensityDataForTrackingButton
        function SaveCh1IntensityDataForTrackingButtonPushed(app, event)
            app.ch1=app.ch1(app.LBY:app.UBY,app.LBX:app.UBX,:);
            app.ch1tooutput=app.ch1;
            app.back=app.ch1;
            app.ch1=uint16(65535*(app.ch1/max(app.ch1(:)))); %%Saved as 16bit
            mkdir([app.path 'Ch1 Files'])
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            imwrite(app.ch1(:,:,1),[app.path 'Ch1 Files\' 'IntChan1X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.tif'])
            for i=2:size(app.ch1,3)
                ph.XData = [0 i/size(app.ch1,3) i/size(app.ch1,3) 0];
                th.String = sprintf('%.0f%%',round(i/size(app.ch1,3)*100));
                drawnow %update graphics
                imwrite(app.ch1(:,:,i),[app.path 'Ch1 Files\' 'IntChan1X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.tif'],'WriteMode','append')
            end
        end

        % Button pushed function: 
        % LoadDiatrackOutputFileFromCh1TrackingButton
        function LoadDiatrackOutputFileFromCh1TrackingButtonPushed(app, event)
            [FileName,PathName] = uigetfile([app.path '*.mat'],'Select the DiaTrack output file containing results of tracking on Ch1');
            load([PathName FileName]);
            % extracting trajectories
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            counterTrack=1;
            for frame=firstFrameSub:lastFrameSub
                for part=find(tracks{frame}.Predecessor==0)
                    framsav=frame;
                    partsav=part;
                    accumtrack(1,:)=[tracks{frame}.RefinedCooY(part),tracks{frame}.RefinedCooX(part),frame];
                    while(tracks{frame}.Successor(part)&&frame<lastFrameSub)
                        part=tracks{frame}.Successor(part);
                        accumtrack(frame-framsav+2,:)=[tracks{frame+1}.RefinedCooY(part),tracks{frame+1}.RefinedCooX(part),frame+1];
                        frame=frame+1;
                    end;
                    app.xtraj{counterTrack}=accumtrack(:,1);
                    app.ytraj{counterTrack}=accumtrack(:,2);
                    app.timePart{counterTrack}=accumtrack(:,3);
                    app.trajLength{counterTrack}=size(accumtrack,1);
                    
                    counterTrack=counterTrack+1;
                    frame=framsav;
                    part=partsav;
                    accumtrack=[];
                end;
                ph.XData = [0 frame/lastFrameSub frame/lastFrameSub 0];
                th.String = sprintf('%.0f%%',round(frame/lastFrameSub*100));
                drawnow %update graphics
            end;
            
            %             radiusToUse=app.radiusToUse;
            %             mintrajsize=app.mintrajsize;
            %             xtraj=app.xtraj;
            %             ytraj=app.ytraj;
            %             timePart=app.timePart;
            %             trajLength=app.trajLength;
            %             save([app.fname '_FLIMperTraj.mat'],'radiusToUse','mintrajsize','xtraj','ytraj','timePart','trajLength')
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.slider=ceil(changingValue);
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.ch1(:,:,app.slider));
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            hold(app.UIAxes,"on");
            colormap(app.UIAxes,'gray')
            text(app.UIAxes,size(app.ch1,2)-30,size(app.ch1,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = app.FramesSlider.Value;
            app.slider=ceil(value);
        end

        % Value changed function: 
        % ImageToUsetoDefineCellShapeDropDown
        function ImageToUsetoDefineCellShapeDropDownValueChanged(app, event)
            value = app.ImageToUsetoDefineCellShapeDropDown.Value;
            app.useToShape=value;
            if strcmp(value,'Temporal Average')
                app.imToShape=mean(app.ch1,3);
            elseif strcmp(value,'Temporal Max Intensity')
                app.imToShape=max(app.ch1,[],3);
            elseif strcmp(value,'Temporal Median')
                app.imToShape=median(app.ch1,3);
            end
            cla(app.UIAxes)
            imagesc(app.UIAxes,app.imToShape);
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
            %set(app.UIAxes,'CLim',[min(app.imToShape(:)) max(app.imToShape(:))])
        end

        % Value changing function: ThresholdCellShapeSlider
        function ThresholdCellShapeSliderValueChanging(app, event)
            changingValue = event.Value/10;
            app.sliderShape=changingValue;
            normim1=app.imToShape/max(app.imToShape(:));
            app.binaryShape=normim1>changingValue;
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,app.binaryShape);
            cla(app.UIAxes)
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.imToShape,2)])
            ylim(app.UIAxes,[1 size(app.imToShape,1)])
        end

        % Value changed function: ThresholdCellShapeSlider
        function ThresholdCellShapeSliderValueChanged(app, event)
            value = app.ThresholdCellShapeSlider.Value;
            app.sliderShape=value/10;
        end

        % Value changed function: 
        % MorphologicalOperationsonBinaryDropDown
        function MorphologicalOperationsonBinaryDropDownValueChanged(app, event)
            value = app.MorphologicalOperationsonBinaryDropDown.Value;
            app.MorphChoice=value;
        end

        % Button pushed function: 
        % PerformMorphologicalOperationsButton
        function PerformMorphologicalOperationsButtonPushed(app, event)
            app.binary2=app.binaryShape;
            if strcmp(app.MorphChoice,'clean')
                app.binary2=bwmorph(app.binaryShape,'clean');
            elseif strcmp(app.MorphChoice,'clean+open')
                app.binary2=bwmorph(app.binaryShape,'clean');
                app.binary2=bwmorph(app.binary2,'open');
                app.binary2=bwmorph(app.binary2,'clean');
            elseif strcmp(app.MorphChoice,'clean+close')
                app.binary2=bwmorph(app.binaryShape,'clean');
                app.binary2=bwmorph(app.binary2,'close');
                app.binary2=bwmorph(app.binary2,'clean');
            end
            %app.binaryShape=app.binary2;
            normim1=app.imToShape/max(app.imToShape(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,app.binary2);
            cla(app.UIAxes)
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.imToShape,2)])
            ylim(app.UIAxes,[1 size(app.imToShape,1)])
            %set(app.UIAxes,'CLim',[min(app.imToShape(:)) max(app.imToShape(:))])
        end

        % Button pushed function: 
        % ExtractCellContourandCenterCoordinatesButton
        function ExtractCellContourandCenterCoordinatesButtonPushed(app, event)
            stats=regionprops(app.binary2,'Area');
            idx = find([stats.Area]==max([stats.Area]));
            L=bwlabel(app.binary2);
            bw=L==idx;
            bw01=imdilate(bw,strel('disk',1));
            stats=regionprops(bw01,'Centroid');
            stats=struct2table(stats);
            app.xc=stats.Centroid(1,1);
            app.yc=stats.Centroid(1,2);
            % now boundary
            bw=bwperim(bw01);
            [B] = bwboundaries(bw,'noholes');
            clear sizeBd
            for i=1:length(B)
                sizeBd(i)=length(B{i});
            end
            indBd=find(sizeBd==max(sizeBd));
            app.boundary = B{indBd};
            %save([app.fname(1:end-4) '_testBdShape.mat'],'stats','bw','bw01','B')
            % show final bw
            normim1=app.imToShape/max(app.imToShape(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,bw01);
            cla(app.UIAxes)
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.imToShape,2)])
            ylim(app.UIAxes,[1 size(app.imToShape,1)])
            %set(app.UIAxes,'CLim',[min(app.imToShape(:)) max(app.imToShape(:))])
        end

        % Value changed function: 
        % MinimumTrajectoryLengthFramesEditField
        function MinimumTrajectoryLengthFramesEditFieldValueChanged(app, event)
            value = app.MinimumTrajectoryLengthFramesEditField.Value;
            app.mintrajsize=value;
        end

        % Button pushed function: IDValidTrajectoriesButton
        function IDValidTrajectoriesButtonPushed(app, event)
            app.indgoodtraj=find(cell2mat(app.trajLength)>app.mintrajsize);
        end

        % Value changed function: 
        % RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField
        function RadiusOfNeighborhoodForLifetimeExtractionpixelsEditFieldValueChanged(app, event)
            value = app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.Value;
            app.radiusToUse=value;
        end

        % Button pushed function: 
        % ExtractLifetimesAlongTrajectoriesAndSaveDataButton
        function ExtractLifetimesAlongTrajectoriesAndSaveDataButtonPushed(app, event)
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            clear timePts xCoord yCoord AveIntCh2b distToC distToBdry
            app.ch2=app.ch2(app.LBY:app.UBY,app.LBX:app.UBX,:);
            jj=1;
            app.allLifetimes=[];
            for j=1:length(app.indgoodtraj);
                times=app.timePart{app.indgoodtraj(j)};
                xinst=app.xtraj{app.indgoodtraj(j)};
                yinst=app.ytraj{app.indgoodtraj(j)};
                clear aveInt2
                for i=1:length(xinst)
                    centx=round(yinst(i));
                    centy=round(xinst(i));
                    if (centx>1+app.radiusToUse || centx+app.radiusToUse<size(app.ch1,1)) && (centy>1+app.radiusToUse || centy+app.radiusToUse<size(app.ch1,2))
                        [e_mask2]=ellipse(size(app.ch1,2),size(app.ch1,1),centx,centy,app.radiusToUse,app.radiusToUse);
                        tmpIm2=e_mask2.*double(squeeze(app.ch2(:,:,times(i))));
                        indNonZero2=find(tmpIm2);
                        totInt=nansum(tmpIm2(indNonZero2));
                        aveInt2(i)=totInt/length(indNonZero2);
                    else
                        aveInt2(i)=NaN;
                    end
                    
                end
                
                app.AveIntCh2{j}=aveInt2;
                % make arrays for saving into excell
                distToC(1:length(xinst),j)=((app.yc-yinst).^2+(app.xc-xinst).^2).^0.5;
                [D,I]=pdist2([app.boundary(:,2),app.boundary(:,1)],[yinst,xinst],'euclidean','Smallest',1);
                distToBdry(1:length(xinst),j)=D;
                xCoord(1:length(xinst),j)=xinst;
                yCoord(1:length(xinst),j)=yinst;
                xCoord(length(xinst)+1:(size(app.ch1,3)),j)=NaN;
                yCoord(length(xinst)+1:(size(app.ch1,3)),j)=NaN;
                times=app.timePart{app.indgoodtraj(j)};
                timePts(1:length(xinst),j)=times;
                timePts(length(xinst)+1:(size(app.ch1,3)),j)=NaN;
                AveIntCh2b(1:length(app.AveIntCh2{j}),j)=app.AveIntCh2{j};
                AveIntCh2b(length(app.AveIntCh2{j})+1:(size(app.ch1,3)),j)=NaN;
                
                app.allLifetimes(1+jj:jj+length(app.AveIntCh2{j}),1)=app.AveIntCh2{j};
                app.allLifetimes(1+jj:jj+length(app.AveIntCh2{j}),2)=j;
                jj=jj+length(app.AveIntCh2{j});
                ph.XData = [0 j/length(app.indgoodtraj) j/length(app.indgoodtraj) 0];
                th.String = sprintf('%.0f%%',round(j/length(app.indgoodtraj)*100));
                drawnow %update graphics
            end
            app.allLifetimes(:,1)=round(app.allLifetimes(:,1)*10^9,2);
            [app.bigmycolors]=ColorMapPtPattern(app.allLifetimes(:,1)');
            
            
            clear T
            T=table(timePts);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','time pts.');
            clear T
            T=table(xCoord);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','X coords');
            clear T
            T=table(yCoord);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','Y coords');
            clear T
            T=table(distToC);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','Distance To Center');
            clear T
            T=table(distToBdry);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','Distance To Boundary');
            clear T
            T=table(AveIntCh2b);
            writetable(T,[app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.xlsx'],'Sheet','FLIM lifetime');
            
            radiusToUse=app.radiusToUse;
            mintrajsize=app.mintrajsize;
            AveIntCh2=app.AveIntCh2;
            xtraj=app.xtraj;
            ytraj=app.ytraj;
            timePart=app.timePart;
            indgoodtraj=app.indgoodtraj;
            save([app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.mat'],'radiusToUse','mintrajsize','AveIntCh2','xtraj','ytraj','timePart','indgoodtraj')
        end

        % Button pushed function: ShowTrajectoriesWithIDButton
        function ShowTrajectoriesWithIDButtonPushed(app, event)
            
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            legend(app.UIAxes,'off');
            xlim(app.UIAxes,[1 size(app.back,2)])
            ylim(app.UIAxes,[1 size(app.back,1)])
            imagesc(app.UIAxes,app.back(:,:,size(app.ch1,3)));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            num5=round(5/app.pixelsize);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,size(app.back,2)-num5-20:size(app.back,2)-21,(size(app.back,1)-5)*ones(1,num5),'-','Color',[1 1 1],'LineWidth',5)
            text(app.UIAxes,size(app.back,2)-num5-15,(size(app.back,1)-15),'5 \mum','Color',[1 1 1],'Fontsize',30)
            for trajectory=1:length(app.AveIntCh2)
                times=app.timePart{app.indgoodtraj(trajectory)};
                xinst=app.xtraj{app.indgoodtraj(trajectory)};
                yinst=app.ytraj{app.indgoodtraj(trajectory)};
                xcm=mean(xinst);
                ycm=mean(yinst);
                inPoly=inpolygon(ycm,xcm,app.xb,app.yb);
                if find(inPoly)==1
                    xinst=xinst-app.rect(2);
                    yinst=yinst-app.rect(1);
                    xcm=mean(xinst);
                    ycm=mean(yinst);
                    theta=0:0.1:2*pi;
                    xcircle=xcm+20*cos(theta);
                    ycircle=ycm+20*sin(theta);
                    intfeat=app.AveIntCh2{trajectory};
                    mycolors=app.bigmycolors(find(app.allLifetimes(:,2)==trajectory),:);
                    j=floor(length(times)-1);
                    hold(app.UIAxes,"on");
                    for t1=1:size(app.ch1,3)
                        indT=find(times==t1);
                        if ~isempty(indT) && indT~=length(xinst)
                            plot(app.UIAxes,[yinst(indT) yinst(indT+1)],[xinst(indT) xinst(indT+1)],'-','Color',mycolors(indT,:),'LineWidth',2)
                            hold(app.UIAxes,"on");
                        end
                    end
                    hold(app.UIAxes,"on");
                end
                text(app.UIAxes,ycm,xcm,num2str(trajectory),'Color',[1 1 1])
                hold(app.UIAxes,"on")
                plot(app.UIAxes,app.boundary(:,2),app.boundary(:,1),'Color',[0 1 0])
                 hold(app.UIAxes,"on")
                plot(app.UIAxes,app.yc,app.xc,'o','Color',[0 0 1])
            end% end trajectories
            
        end

        % Value changed function: WhichTrajectoryToDisplayEditField
        function WhichTrajectoryToDisplayEditFieldValueChanged(app, event)
            value = app.WhichTrajectoryToDisplayEditField.Value;
            app.DispTraj=value;
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;
            if strcmp(value,'Raw FLIM trace')
                app.MovAveFilt='raw';
            elseif strcmp(value,'Moving Averaged')
                app.MovAveFilt='movmean';
            elseif strcmp(value,'Moving Median')
                app.MovAveFilt='movmedian';
            elseif strcmp(value,'Gaussian')
                app.MovAveFilt='gaussian';
            elseif strcmp(value,'Lowess')
                app.MovAveFilt='lowess';
            elseif strcmp(value,'Loess')
                app.MovAveFilt='loess';
            elseif strcmp(value,'Robust Lowess')
                app.MovAveFilt='rlowess';
            elseif strcmp(value,'Robust Loess')
                app.MovAveFilt='rloess';
            elseif strcmp(value,'Savitzky-Golay')
                app.MovAveFilt='sgolay';
            end
        end

        % Value changed function: MovingWindowSizeEditField
        function MovingWindowSizeEditFieldValueChanged(app, event)
            value = app.MovingWindowSizeEditField.Value;
            app.MovAveFiltSize=value;
        end

        % Button pushed function: 
        % FilterifneededanddisplayFLIMtracevstimeButton
        function FilterifneededanddisplayFLIMtracevstimeButtonPushed(app, event)
            times=app.timePart{app.indgoodtraj(app.DispTraj)};
            X=app.AveIntCh2{1,app.DispTraj}*10^9;
            if strcmp(app.MovAveFilt,'raw')
                Y=X;
            else
                Y = smoothdata(X,app.MovAveFilt,app.MovAveFiltSize);
            end
            cla(app.UIAxes)
            cla(app.UIAxes3)
            app.UIAxes.YDir='normal';
            title(app.UIAxes,['FLIM signal along trajectory ' num2str(app.DispTraj) ])
            plot(app.UIAxes,times,X,'-b')
            hold(app.UIAxes,"on");
            plot(app.UIAxes,times,Y,'--r')
            xlabel(app.UIAxes,'time (frames)','FontSize',16)
            ylabel(app.UIAxes,'\tau_{FLIM} (ns)','FontSize',16)
            legend(app.UIAxes,{'raw \tau','filt \tau'},'FontSize',16)
            xlim(app.UIAxes,[min(times) max(times)])
            ylim(app.UIAxes,[min(X) max(X)])
            app.UIAxes.XTick = [min(times):10:max(times)];
            %xticks(app.UIAxes,[min(times):5:max(times)],'FontSize',16)
            delX=max(X)-min(X);
            app.UIAxes.YTick = [min(X):delX/10:max(X)];
            set(app.UIAxes,'FontSize',16)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.RangeDecision=value;
            if ~isempty(app.rangeY)
                app.IndGoodRange=~app.IndGoodRange;
            end
            if strcmp(app.RangeDecision,'>=')
                app.addtofile='gorequal';
            elseif strcmp(app.RangeDecision,'<')
                app.addtofile='less';
            end
            IndGoodRange=app.IndGoodRange;
            save([app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.mat'],'IndGoodRange','-append')
        end

        % Value changed function: ConfidenceRangeLifetimeEditField
        function ConfidenceRangeLifetimeEditFieldValueChanged(app, event)
            value = app.ConfidenceRangeLifetimeEditField.Value;
            app.DeltaFlimThresh=value;
        end

        % Button pushed function: SaveFLIMFilteredTracesButton
        function SaveFLIMFilteredTracesButtonPushed(app, event)
            clear AveIntCh3b
            app.rangeY=[];
            app.IndGoodRange=[];
            app.AveIntCh3=[];
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for j=1:length(app.AveIntCh2)
                Y = smoothdata(app.AveIntCh2{j},app.MovAveFilt,app.MovAveFiltSize);
                AveIntCh3b(1:length(Y),j)=Y;
                AveIntCh3b(length(Y)+1:(size(app.ch1,3)),j)=NaN;
                app.AveIntCh3{j}=Y;
                % define good trajectories accodring to lifetime range
                app.rangeY(j)=(max(Y)-min(Y))*10^9;
                if strcmp(app.RangeDecision,'>=')
                    if app.rangeY(j)>=app.DeltaFlimThresh
                        app.IndGoodRange(j)=1;
                    elseif app.rangeY(j)<app.DeltaFlimThresh
                        app.IndGoodRange(j)=0;
                    end
                elseif strcmp(app.RangeDecision,'<')
                    if app.rangeY(j)>=app.DeltaFlimThresh
                        app.IndGoodRange(j)=0;
                    elseif app.rangeY(j)<app.DeltaFlimThresh
                        app.IndGoodRange(j)=1;
                    end
                end
                ph.XData = [0 j/length(app.AveIntCh2) j/length(app.AveIntCh2) 0];
                th.String = sprintf('%.0f%%',round(j/length(app.AveIntCh2)*100));
                drawnow %update graphics
            end
            T=table(AveIntCh3b);
            writetable(T,[app.fname '_FLIMperTraj.xlsx'],'Sheet',['Smoothed ' app.MovAveFilt num2str(app.MovAveFiltSize)]);
            MovAveFilt=app.MovAveFilt;
            MovAveFiltSize=app.MovAveFiltSize;
            IndGoodRange=app.IndGoodRange;
            AveIntCh3=app.AveIntCh3;
            save([app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.mat'],'AveIntCh3','MovAveFiltSize','MovAveFilt','IndGoodRange','-append')
        end

        % Value changed function: 
        % IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField
        function IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditFieldValueChanged(app, event)
            value = app.IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField.Value;
            value=str2num(value);
            if sum(value)==0
                app.IndGoodToPlot=1:length(app.AveIntCh2);
            elseif sum(value)~=0
                app.IndGoodToPlot=value;
            end
            app.allLifetimes=[];
            combineGood=app.IndGoodToPlot;
            app.AveIntCh2b=app.AveIntCh2(app.IndGoodToPlot);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            j=0;
            traj=1;
            for i=1:length(app.AveIntCh2b)
                xinst=app.xtraj{app.indgoodtraj(combineGood(i))};
                yinst=app.ytraj{app.indgoodtraj(combineGood(i))};
                xcm=mean(xinst);
                ycm=mean(yinst);
                inPoly=inpolygon(ycm,xcm,app.xb,app.yb);
                if find(inPoly)==1
                    app.allLifetimes(1+j:j+length(app.AveIntCh2b{i}),1)=app.AveIntCh2b{i};
                    app.allLifetimes(1+j:j+length(app.AveIntCh2b{i}),2)=traj;
                    traj=traj+1;
                    j=j+length(app.AveIntCh2b{i});
                end
                ph.XData = [0 i/length(app.AveIntCh2b)  i/length(app.AveIntCh2b) 0];
                th.String = sprintf('%.0f%%',round(i/length(app.AveIntCh2b)*100));
                drawnow %update graphics
            end
            app.allLifetimes(:,1)=round(app.allLifetimes(:,1)*10^9,2);
            [app.bigmycolors]=ColorMapPtPattern(app.allLifetimes(:,1)');
            IndGoodToPlot=app.IndGoodToPlot;
            save([app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.mat'],'IndGoodToPlot','-append')
        end

        % Value changed function: UseFilteredLifetimeValuesButton
        function UseFilteredLifetimeValuesButtonValueChanged(app, event)
            value = app.UseFilteredLifetimeValuesButton.Value;
            app.UseFilteredTraj=value;
            app.allLifetimes=[];
            if app.UseFilteredTraj==1
                combineGood=intersect(app.IndGoodToPlot,find(app.IndGoodRange));
                app.AveIntCh2b=app.AveIntCh3(combineGood);
            elseif app.UseFilteredTraj==0
                app.AveIntCh2b=app.AveIntCh2(app.IndGoodToPlot);
                combineGood=app.IndGoodToPlot;
            end
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            j=0;
            traj=1;
            for i=1:length(app.AveIntCh2b)
                xinst=app.xtraj{app.indgoodtraj(combineGood(i))};
                yinst=app.ytraj{app.indgoodtraj(combineGood(i))};
                xcm=mean(xinst);
                ycm=mean(yinst);
                inPoly=inpolygon(ycm,xcm,app.xb,app.yb);
                if find(inPoly)==1
                    app.allLifetimes(1+j:j+length(app.AveIntCh2b{i}),1)=app.AveIntCh2b{i};
                    app.allLifetimes(1+j:j+length(app.AveIntCh2b{i}),2)=traj;
                    traj=traj+1;
                    j=j+length(app.AveIntCh2b{i});
                end
                ph.XData = [0 i/length(app.AveIntCh2b)  i/length(app.AveIntCh2b) 0];
                th.String = sprintf('%.0f%%',round(i/length(app.AveIntCh2b)*100));
                drawnow %update graphics
            end
            allLifetimes=app.allLifetimes;
            save([app.fname '_FLIMperTrajX' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '.mat'],'allLifetimes','combineGood','-append')
            app.allLifetimes(:,1)=round(app.allLifetimes(:,1)*10^9,2);
            [app.bigmycolors]=ColorMapPtPattern(app.allLifetimes(:,1)');
            %             allLifetimes=app.allLifetimes;
            %             bigmycolors=app.bigmycolors;
            %             save([app.fname '_FLIMperTraj.mat'],'allLifetimes','bigmycolors','combineGood','-append')
        end

        % Value changed function: 
        % CropAreaWithinImageToDefineAPartofFOVtoOutputButton
        function CropAreaWithinImageToDefineAPartofFOVtoOutputButtonValueChanged(app, event)
            value = app.CropAreaWithinImageToDefineAPartofFOVtoOutputButton.Value;
            app.CropArea=value;
            app.ch1tooutput=[];
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            legend(app.UIAxes,'off');
            if app.CropArea==1
                cla(app.UIAxes)
                title(app.UIAxes,'Please select the rectangle you would like to crop and export movie off')
                h=imagesc(app.UIAxes,app.ch1(:,:,1));
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                hold(app.UIAxes,"on");
                colormap(app.UIAxes,'gray')
                [J,app.rect]=imcrop(h);
                for i=1:size(app.ch1,3)
                    app.ch1tooutput(:,:,i)=imcrop(app.ch1(:,:,i),app.rect);
                end
                app.addtoname=['rect_x' num2str(round(app.rect(1))) 'y' num2str(round(app.rect(2))) 'w' num2str(round(app.rect(3))) 'h' num2str(round(app.rect(4)))];
                app.xb(1)=app.rect(1);
                app.xb(2)=app.rect(1)+app.rect(3);
                app.xb(3)=app.rect(1)+app.rect(3);
                app.xb(4)=app.rect(1);
                app.yb(1)=app.rect(2);
                app.yb(2)=app.rect(2);
                app.yb(3)=app.rect(2)+app.rect(4);
                app.yb(4)=app.rect(2)+app.rect(4);
            elseif app.CropArea==0
                app.ch1tooutput=app.ch1;
                app.addtoname='nocrop';
                app.xb(1)=1;
                app.xb(2)=size(app.ch1,2);
                app.xb(3)=size(app.ch1,2);
                app.xb(4)=1;
                app.yb(1)=1;
                app.yb(2)=1;
                app.yb(3)=size(app.ch1,1);
                app.yb(4)=size(app.ch1,1);
                app.rect(1:2)=0;
                app.rect(3)=size(app.ch1,2);
                app.rect(4)=size(app.ch1,1);
            end
            app.back=app.ch1tooutput;
        end

        % Value changed function: LoGFilterSigmaEditField
        function LoGFilterSigmaEditFieldValueChanged(app, event)
            value = app.LoGFilterSigmaEditField.Value;
            app.LoGSig=value;
        end

        % Value changed function: 
        % ApplyLoGFiltertoBackgroundImageButton
        function ApplyLoGFiltertoBackgroundImageButtonValueChanged(app, event)
            value = app.ApplyLoGFiltertoBackgroundImageButton.Value;
            if value==1;
                app.filt=smoothSeries2(app.ch1tooutput,app.LoGSig,'log','n');
            elseif value==0
                app.filt=app.ch1tooutput;
            end
            app.back=app.filt;
        end

        % Value changed function: 
        % TakeaLogOfBackgroundIntensityCh1ImageButton
        function TakeaLogOfBackgroundIntensityCh1ImageButtonValueChanged(app, event)
            value = app.TakeaLogOfBackgroundIntensityCh1ImageButton.Value;
            if value==1;
                for i=1:size(app.ch1,3)
                    app.logged(:,:,i)=log(app.filt(:,:,i));
                end
            elseif value==0
                app.logged=app.filt;
            end
            app.back=app.logged;
        end

        % Value changed function: WhatMovieTypeToExportDropDown
        function WhatMovieTypeToExportDropDownValueChanged(app, event)
            value = app.WhatMovieTypeToExportDropDown.Value;
            app.movType=value;
        end

        % Value changed function: 
        % WhatFrameRateDoYouWantToSaveVideofpsEditField
        function WhatFrameRateDoYouWantToSaveVideofpsEditFieldValueChanged(app, event)
            value = app.WhatFrameRateDoYouWantToSaveVideofpsEditField.Value;
            app.movFPS=value;
        end

        % Button pushed function: ExportFirst15FramesTestButton
        function ExportFirst15FramesTestButtonPushed(app, event)
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            legend(app.UIAxes,'off');
            if strcmp(app.movType,'mp4')
                writerObj1 = VideoWriter([app.fname num2str(app.movFPS)  'subset_fps_15frames_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile '.mp4'],'MPEG-4');
            elseif strcmp(app.movType,'avi')
                writerObj1 = VideoWriter([app.fname num2str(app.movFPS)  'subset_fps_15frames_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile '.avi'],'Uncompressed AVI');
            end
            
            writerObj1.FrameRate =app.movFPS;
            open(writerObj1);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            theta=0:0.1:2*pi;
            tottime=15;
            cla(app.UIAxes3)
            if app.UseFilteredTraj==1
                combineGood=intersect(app.IndGoodToPlot,find(app.IndGoodRange));
            elseif app.UseFilteredTraj==0
                combineGood=app.IndGoodToPlot;
            end
            
            for t=1:tottime
                cla(app.UIAxes)
                
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                xlim(app.UIAxes,[1 size(app.back,2)])
                ylim(app.UIAxes,[1 size(app.back,1)])
                title(app.UIAxes,'')
                %tilelay = tiledlayout(app.UIAxes,21,19);
                %sub1=nexttile(19,19);
                imagesc(app.UIAxes,app.back(:,:,t));
                colormap(app.UIAxes,'gray')
                % set(app.UIAxes,'CLim',[squeeze(min(min(app.back(:,:,t)))) squeeze(max(max(app.back(:,:,t))))])
                hold(app.UIAxes,"on");
                text(app.UIAxes,5,10,['t= ' num2str((t*app.timeFrame)) ' s'],'Color',[1 1 1],'Fontsize',30)
                hold(app.UIAxes,"on");
                num5=round(5/app.pixelsize);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,size(app.back,2)-num5-10:size(app.back,2)-11,(size(app.back,1)-5)*ones(1,num5),'-','Color',[1 1 1],'LineWidth',5)
                text(app.UIAxes,size(app.back,2)-num5,(size(app.back,1)-13),'5 \mum','Color',[1 1 1],'Fontsize',30)
                traj=1;
                for trajectory=1:length(app.AveIntCh2b)
                    times=app.timePart{app.indgoodtraj(combineGood(trajectory))};
                    xinst=app.xtraj{app.indgoodtraj(combineGood(trajectory))};
                    yinst=app.ytraj{app.indgoodtraj(combineGood(trajectory))};
                    xcm=mean(xinst);
                    ycm=mean(yinst);
                    inPoly=inpolygon(ycm,xcm,app.xb,app.yb);
                    if find(inPoly)==1
                        xinst=xinst-app.rect(2);
                        yinst=yinst-app.rect(1);
                        xcm=mean(xinst);
                        ycm=mean(yinst);
                        xcircle=xcm+20*cos(theta);
                        ycircle=ycm+20*sin(theta);
                        intfeat=app.AveIntCh2b{trajectory};
                        mycolors=app.bigmycolors(find(app.allLifetimes(:,2)==traj),:);
                        j=floor(length(times)-1);
                        hold(app.UIAxes,"on");
                        for t1=1:t
                            indT=find(times==t1);
                            if ~isempty(indT) && indT~=length(xinst)
                                plot(app.UIAxes,[yinst(indT) yinst(indT+1)],[xinst(indT) xinst(indT+1)],'-','Color',mycolors(indT,:),'LineWidth',2)
                                hold(app.UIAxes,"on");
                            end
                        end
                        hold(app.UIAxes,"on");
                        traj=traj+1;
                    end
                end% end trajectories
                hold(app.UIAxes,"off");
                %set(gcf,'Position',[315          75        1503         916])
                mycolors=jet(255);
                %sub2=nexttile(21,1);
                minLife=min(app.allLifetimes(:,1)*10^9);
                maxLife=max(app.allLifetimes(:,1)*10^9);
                colmat=ind2rgb(repmat(1:length(mycolors),length(mycolors),1),mycolors);
                imagesc(app.UIAxes3,colmat);
                xlim(app.UIAxes3,[1 size(colmat,2)])
                ylim(app.UIAxes3,[1 size(colmat,1)])
                rangeLife=minLife+(maxLife-minLife)/3:(maxLife-minLife)/3:maxLife-(maxLife-minLife)/3;
                set(app.UIAxes3,'XTick',[length(mycolors)/3 2*length(mycolors)/3],'XTickLabel',{num2str(rangeLife(1),'%1.2f');num2str(rangeLife(2),'%1.2f')},'YTick',[]);
                %set(gca,'XTick',[],'YTick',[]);
                %daspect([1 9 1])
                text(app.UIAxes3,min(get(app.UIAxes3,'XLim')),mean(get(app.UIAxes3,'YLim')),{[num2str(minLife,'%1.2f') ' '];'ns '},'HorizontalAlignment','right')
                text(app.UIAxes3,max(get(app.UIAxes3,'XLim')),mean(get(app.UIAxes3,'YLim')),{[' ' num2str(maxLife,'%1.2f')];' ns'},'HorizontalAlignment','left')
                
                frame = getframe(app.UIFigure,[485 155 632 640]);%[485 145 630 655]);%[505 145 585 665]);%[500 145 605 655]);
                writeVideo(writerObj1,frame);
                ph.XData = [0 t/tottime  t/tottime 0];
                th.String = sprintf('%.0f%%',round(t/tottime*100));
                drawnow %update graphics
            end
            close(writerObj1);
            
            % export last frame to a png
            
            %             hold(app.UIAxes,"on")
            %             for traj=1:length(app.AveIntCh2b)
            %                 xinst=app.xtraj{app.indgoodtraj(combineGood(traj))};
            %                 yinst=app.ytraj{app.indgoodtraj(combineGood(traj))};
            %                 xcm=mean(xinst);
            %                 ycm=mean(yinst);
            %                 text(app.UIAxes,xcm,ycm,num2str(combineGood(traj)),'Color',[1 1 1])
            %                 hold(app.UIAxes,"on")
            %             end
            exportgraphics(app.UIAxes,[app.fname 'SPT_FLIM_' app.addtoname '_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile  '.png'])
            exportgraphics(app.UIAxes3,[app.fname 'SPT_FLIM_' app.addtoname '_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile  '_colorbar.png'])
        end

        % Button pushed function: ExportMovieButton
        function ExportMovieButtonPushed(app, event)
            xlim(app.UIAxes,[1 size(app.ch1,2)])
            ylim(app.UIAxes,[1 size(app.ch1,1)])
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            legend(app.UIAxes,'off');
            title(app.UIAxes,'')
            if strcmp(app.movType,'mp4')
                writerObj1 = VideoWriter([app.fname num2str(app.movFPS)  'fps_full_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile '.mp4'],'MPEG-4');
            elseif strcmp(app.movType,'avi')
                writerObj1 = VideoWriter([app.fname num2str(app.movFPS)  'fps_full_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile '.avi'],'Uncompressed AVI');
            end
            
            writerObj1.FrameRate =app.movFPS;
            open(writerObj1);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            theta=0:0.1:2*pi;
            tottime=size(app.ch1,3);
            cla(app.UIAxes3)
            
            if app.UseFilteredTraj==1
                combineGood=intersect(app.IndGoodToPlot,find(app.IndGoodRange));
            elseif app.UseFilteredTraj==0
                combineGood=app.IndGoodToPlot;
            end
            
            for t=1:tottime
                cla(app.UIAxes)
                
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                xlim(app.UIAxes,[1 size(app.back,2)])
                ylim(app.UIAxes,[1 size(app.back,1)])
                %tilelay = tiledlayout(app.UIAxes,21,19);
                %sub1=nexttile(19,19);
                imagesc(app.UIAxes,app.back(:,:,t));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,5,10,['t= ' num2str((t*app.timeFrame)) ' s'],'Color',[1 1 1],'Fontsize',30)
                hold(app.UIAxes,"on");
                num5=round(5/app.pixelsize);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,size(app.back,2)-num5-10:size(app.back,2)-11,(size(app.back,1)-5)*ones(1,num5),'-','Color',[1 1 1],'LineWidth',5)
                text(app.UIAxes,size(app.back,2)-num5-5,(size(app.back,1)-15),'5 \mum','Color',[1 1 1],'Fontsize',30)
                traj=1;
                for trajectory=1:length(app.AveIntCh2b)
                    times=app.timePart{app.indgoodtraj(combineGood(trajectory))};
                    xinst=app.xtraj{app.indgoodtraj(combineGood(trajectory))};
                    yinst=app.ytraj{app.indgoodtraj(combineGood(trajectory))};
                    xcm=mean(xinst);
                    ycm=mean(yinst);
                    inPoly=inpolygon(ycm,xcm,app.xb,app.yb);
                    if find(inPoly)==1
                        xinst=xinst-app.rect(2);
                        yinst=yinst-app.rect(1);
                        xcm=mean(xinst);
                        ycm=mean(yinst);
                        xcircle=xcm+20*cos(theta);
                        ycircle=ycm+20*sin(theta);
                        intfeat=app.AveIntCh2b{trajectory};
                        mycolors=app.bigmycolors(find(app.allLifetimes(:,2)==traj),:);
                        j=floor(length(times)-1);
                        hold(app.UIAxes,"on");
                        for t1=1:t
                            indT=find(times==t1);
                            if ~isempty(indT) && indT~=length(xinst)
                                plot(app.UIAxes,[yinst(indT) yinst(indT+1)],[xinst(indT) xinst(indT+1)],'-','Color',mycolors(indT,:),'LineWidth',2)
                                hold(app.UIAxes,"on");
                            end
                        end
                        hold(app.UIAxes,"on");
                        traj=traj+1;
                    end
                end% end trajectories
                hold(app.UIAxes,"off");
  
                mycolors=jet(255);
                %sub2=nexttile(21,1);
                minLife=min(app.allLifetimes(:,1)*10^9);
                maxLife=max(app.allLifetimes(:,1)*10^9);
                colmat=ind2rgb(repmat(1:length(mycolors),length(mycolors),1),mycolors);
                imagesc(app.UIAxes3,colmat);
                xlim(app.UIAxes3,[1 size(colmat,2)])
                ylim(app.UIAxes3,[1 size(colmat,1)])
                rangeLife=minLife+(maxLife-minLife)/3:(maxLife-minLife)/3:maxLife-(maxLife-minLife)/3;
                set(app.UIAxes3,'XTick',[length(mycolors)/3 2*length(mycolors)/3],'XTickLabel',{num2str(rangeLife(1),'%1.2f');num2str(rangeLife(2),'%1.2f')},'YTick',[]);
                %set(gca,'XTick',[],'YTick',[]);
                %daspect([1 9 1])
                text(app.UIAxes3,min(get(app.UIAxes3,'XLim')),mean(get(app.UIAxes3,'YLim')),{[num2str(minLife,'%1.2f') ' '];'ns '},'HorizontalAlignment','right')
                text(app.UIAxes3,max(get(app.UIAxes3,'XLim')),mean(get(app.UIAxes3,'YLim')),{[' ' num2str(maxLife,'%1.2f')];' ns'},'HorizontalAlignment','left')
                
                frame = getframe(app.UIFigure,[485 155 632 640]);%[485 145 630 655]);%[505 145 585 665]);%[500 145 605 655]);
                writeVideo(writerObj1,frame);
                ph.XData = [0 t/tottime  t/tottime 0];
                th.String = sprintf('%.0f%%',round(t/tottime*100));
                drawnow %update graphics
            end
            close(writerObj1);
            
            % export last frame to a png
            %
            %             hold(app.UIAxes,"on")
            %             for traj=1:length(app.AveIntCh2b)
            %                 xinst=app.xtraj{app.indgoodtraj(combineGood(traj))};
            %                 yinst=app.ytraj{app.indgoodtraj(combineGood(traj))};
            %                 xcm=mean(xinst);
            %                 ycm=mean(yinst);
            %                 text(app.UIAxes,xcm,ycm,num2str(combineGood(traj)),'Color',[1 1 1])
            %                 hold(app.UIAxes,"on")
            %             end
            exportgraphics(app.UIAxes,[app.fname 'SPT_FLIM_' app.addtoname '_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile '.png'])
            exportgraphics(app.UIAxes3,[app.fname 'SPT_FLIM_' app.addtoname '_X' num2str(round(app.LBX)) 'to' num2str(round(app.UBX)) 'Y' num2str(round(app.LBY)) 'to' num2str(round(app.UBY)) '_' app.addtofile  '_colorbar.png'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 100];
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.FramesSlider.Value = 1;
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadTIFFLIMDataFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadTIFFLIMDataFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTIFFLIMDataFilesButtonPushed, true);
            app.LoadTIFFLIMDataFilesButton.Text = 'Load TIF FLIM Data Files';
            app.SaveCh1IntensityDataForTrackingButton = uibutton(app.LoadDataPanel, 'push');
            app.SaveCh1IntensityDataForTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCh1IntensityDataForTrackingButtonPushed, true);
            app.SaveCh1IntensityDataForTrackingButton.Text = 'Save Ch1 Intensity Data For Tracking';
            app.LoadDiatrackOutputFileFromCh1TrackingButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadDiatrackOutputFileFromCh1TrackingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDiatrackOutputFileFromCh1TrackingButtonPushed, true);
            app.LoadDiatrackOutputFileFromCh1TrackingButton.Text = 'Load Diatrack Output File From Ch1 Tracking';
            app.StringForOutputFileNameEditFieldLabel = uilabel(app.LoadDataPanel);
            app.StringForOutputFileNameEditFieldLabel.Text = 'String For Output File Name';
            app.StringForOutputFileNameEditField = uieditfield(app.LoadDataPanel, 'text');
            app.StringForOutputFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @StringForOutputFileNameEditFieldValueChanged, true);
            app.StringForOutputFileNameEditField.Value = 'FLIMdata';
            app.FrametimesecondsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FrametimesecondsEditFieldLabel.Text = 'Frame time (seconds)';
            app.FrametimesecondsEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.FrametimesecondsEditField.Limits = [0 Inf];
            app.FrametimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimesecondsEditFieldValueChanged, true);
            app.FrametimesecondsEditField.Value = 0.5;
            app.PixelSizemicrometerEditFieldLabel = uilabel(app.LoadDataPanel);
            app.PixelSizemicrometerEditFieldLabel.Text = 'Pixel Size (micrometer)';
            app.PixelSizemicrometerEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.PixelSizemicrometerEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemicrometerEditFieldValueChanged, true);
            app.PixelSizemicrometerEditField.Value = 0.1;
            app.LBXSliderLabel = uilabel(app.LoadDataPanel);
            app.LBXSliderLabel.Text = 'LB X';
            app.LBXSlider = uislider(app.LoadDataPanel);
            app.LBXSlider.Limits = [1 100];
            app.LBXSlider.ValueChangedFcn = createCallbackFcn(app, @LBXSliderValueChanged, true);
            app.LBXSlider.ValueChangingFcn = createCallbackFcn(app, @LBXSliderValueChanging, true);
            app.LBXSlider.Value = 1;
            app.UBXSliderLabel = uilabel(app.LoadDataPanel);
            app.UBXSliderLabel.Text = 'UB X';
            app.UBXSlider = uislider(app.LoadDataPanel);
            app.UBXSlider.Limits = [1 100];
            app.UBXSlider.ValueChangedFcn = createCallbackFcn(app, @UBXSliderValueChanged, true);
            app.UBXSlider.ValueChangingFcn = createCallbackFcn(app, @UBXSliderValueChanging, true);
            app.UBXSlider.Value = 1;
            app.LBYSliderLabel = uilabel(app.LoadDataPanel);
            app.LBYSliderLabel.Text = 'LB Y';
            app.LBYSlider = uislider(app.LoadDataPanel);
            app.LBYSlider.Limits = [1 100];
            app.LBYSlider.ValueChangedFcn = createCallbackFcn(app, @LBYSliderValueChanged, true);
            app.LBYSlider.ValueChangingFcn = createCallbackFcn(app, @LBYSliderValueChanging, true);
            app.LBYSlider.Value = 1;
            app.UBYSliderLabel = uilabel(app.LoadDataPanel);
            app.UBYSliderLabel.Text = 'UB Y';
            app.UBYSlider = uislider(app.LoadDataPanel);
            app.UBYSlider.Limits = [1 100];
            app.UBYSlider.ValueChangedFcn = createCallbackFcn(app, @UBYSliderValueChanged, true);
            app.UBYSlider.ValueChangingFcn = createCallbackFcn(app, @UBYSliderValueChanging, true);
            app.UBYSlider.Value = 1;
            app.FilterShortTrajectoriesAndExtractLifetimesPanel = uipanel(app.UIFigure);
            app.FilterShortTrajectoriesAndExtractLifetimesPanel.Title = 'Filter Short Trajectories And Extract Lifetimes ';
            app.MinimumTrajectoryLengthFramesEditFieldLabel = uilabel(app.FilterShortTrajectoriesAndExtractLifetimesPanel);
            app.MinimumTrajectoryLengthFramesEditFieldLabel.Text = 'Minimum Trajectory Length (Frames)';
            app.MinimumTrajectoryLengthFramesEditField = uieditfield(app.FilterShortTrajectoriesAndExtractLifetimesPanel, 'numeric');
            app.MinimumTrajectoryLengthFramesEditField.Limits = [1 Inf];
            app.MinimumTrajectoryLengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumTrajectoryLengthFramesEditFieldValueChanged, true);
            app.MinimumTrajectoryLengthFramesEditField.Value = 10;
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditFieldLabel = uilabel(app.FilterShortTrajectoriesAndExtractLifetimesPanel);
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditFieldLabel.Text = 'Radius Of Neighborhood For Lifetime Extraction (pixels)';
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField = uieditfield(app.FilterShortTrajectoriesAndExtractLifetimesPanel, 'numeric');
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.Limits = [1 Inf];
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.RoundFractionalValues = 'on';
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusOfNeighborhoodForLifetimeExtractionpixelsEditFieldValueChanged, true);
            app.RadiusOfNeighborhoodForLifetimeExtractionpixelsEditField.Value = 3;
            app.IDValidTrajectoriesButton = uibutton(app.FilterShortTrajectoriesAndExtractLifetimesPanel, 'push');
            app.IDValidTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @IDValidTrajectoriesButtonPushed, true);
            app.IDValidTrajectoriesButton.Text = 'ID Valid Trajectories';
            app.ExtractLifetimesAlongTrajectoriesAndSaveDataButton = uibutton(app.FilterShortTrajectoriesAndExtractLifetimesPanel, 'push');
            app.ExtractLifetimesAlongTrajectoriesAndSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractLifetimesAlongTrajectoriesAndSaveDataButtonPushed, true);
            app.ExtractLifetimesAlongTrajectoriesAndSaveDataButton.Text = 'Extract Lifetimes Along Trajectories And Save Data';
            app.GenerateFLIMTrajectoriesMoviePanel = uipanel(app.UIFigure);
            app.GenerateFLIMTrajectoriesMoviePanel.Title = 'Generate FLIM Trajectories Movie';
            app.ApplyLoGFiltertoBackgroundImageButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'state');
            app.ApplyLoGFiltertoBackgroundImageButton.ValueChangedFcn = createCallbackFcn(app, @ApplyLoGFiltertoBackgroundImageButtonValueChanged, true);
            app.ApplyLoGFiltertoBackgroundImageButton.Text = 'Apply LoG Filter to Background Image?';
            app.LoGFilterSigmaEditFieldLabel = uilabel(app.GenerateFLIMTrajectoriesMoviePanel);
            app.LoGFilterSigmaEditFieldLabel.Text = 'LoG Filter Sigma ';
            app.LoGFilterSigmaEditField = uieditfield(app.GenerateFLIMTrajectoriesMoviePanel, 'numeric');
            app.LoGFilterSigmaEditField.ValueChangedFcn = createCallbackFcn(app, @LoGFilterSigmaEditFieldValueChanged, true);
            app.LoGFilterSigmaEditField.Value = 1.5;
            app.WhatMovieTypeToExportDropDownLabel = uilabel(app.GenerateFLIMTrajectoriesMoviePanel);
            app.WhatMovieTypeToExportDropDownLabel.Text = 'What Movie Type To Export?';
            app.WhatMovieTypeToExportDropDown = uidropdown(app.GenerateFLIMTrajectoriesMoviePanel);
            app.WhatMovieTypeToExportDropDown.Items = {'mp4', 'avi'};
            app.WhatMovieTypeToExportDropDown.ValueChangedFcn = createCallbackFcn(app, @WhatMovieTypeToExportDropDownValueChanged, true);
            app.WhatMovieTypeToExportDropDown.Value = 'mp4';
            app.WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel = uilabel(app.GenerateFLIMTrajectoriesMoviePanel);
            app.WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel.Text = 'What Frame Rate Do You Want To Save Video (fps)';
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField = uieditfield(app.GenerateFLIMTrajectoriesMoviePanel, 'numeric');
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField.ValueChangedFcn = createCallbackFcn(app, @WhatFrameRateDoYouWantToSaveVideofpsEditFieldValueChanged, true);
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField.Value = 5;
            app.ExportMovieButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'push');
            app.ExportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMovieButtonPushed, true);
            app.ExportMovieButton.Text = 'Export Movie';
            app.ExportFirst15FramesTestButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'push');
            app.ExportFirst15FramesTestButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFirst15FramesTestButtonPushed, true);
            app.ExportFirst15FramesTestButton.Text = 'Export First 15 Frames Test';
            app.IDGoodToDisplayTrajectoriesEditFieldLabel = uilabel(app.GenerateFLIMTrajectoriesMoviePanel);
            app.IDGoodToDisplayTrajectoriesEditFieldLabel.Text = 'ID Good To Display Trajectories (tab separated integers, 0 if all)';
            app.IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField = uieditfield(app.GenerateFLIMTrajectoriesMoviePanel, 'text');
            app.IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField.ValueChangedFcn = createCallbackFcn(app, @IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditFieldValueChanged, true);
            app.IDGoodToDisplayTrajectoriestabseparatedintegers0ifallEditField.Value = '1 3 5 7 10:5:30';
            app.UseFilteredLifetimeValuesButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'state');
            app.UseFilteredLifetimeValuesButton.ValueChangedFcn = createCallbackFcn(app, @UseFilteredLifetimeValuesButtonValueChanged, true);
            app.UseFilteredLifetimeValuesButton.Text = 'Use Filtered Lifetime Values';
            app.CropAreaWithinImageToDefineAPartofFOVtoOutputButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'state');
            app.CropAreaWithinImageToDefineAPartofFOVtoOutputButton.ValueChangedFcn = createCallbackFcn(app, @CropAreaWithinImageToDefineAPartofFOVtoOutputButtonValueChanged, true);
            app.CropAreaWithinImageToDefineAPartofFOVtoOutputButton.Text = 'Crop Area Within Image To Define A Part of FOV to Output?';
            app.TakeaLogOfBackgroundIntensityCh1ImageButton = uibutton(app.GenerateFLIMTrajectoriesMoviePanel, 'state');
            app.TakeaLogOfBackgroundIntensityCh1ImageButton.ValueChangedFcn = createCallbackFcn(app, @TakeaLogOfBackgroundIntensityCh1ImageButtonValueChanged, true);
            app.TakeaLogOfBackgroundIntensityCh1ImageButton.Text = 'Take a Log Of Background Intensity (Ch1) Image? ';
            app.ExploreAndFilterFLIMTracesPanel = uipanel(app.UIFigure);
            app.ExploreAndFilterFLIMTracesPanel.Title = 'Explore And Filter FLIM Traces ';
            app.ShowTrajectoriesWithIDButton = uibutton(app.ExploreAndFilterFLIMTracesPanel, 'push');
            app.ShowTrajectoriesWithIDButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTrajectoriesWithIDButtonPushed, true);
            app.ShowTrajectoriesWithIDButton.Text = 'Show Trajectories With ID';
            app.WhichTrajectoryToDisplayEditFieldLabel = uilabel(app.ExploreAndFilterFLIMTracesPanel);
            app.WhichTrajectoryToDisplayEditFieldLabel.Text = 'Which Trajectory To Display?';
            app.WhichTrajectoryToDisplayEditField = uieditfield(app.ExploreAndFilterFLIMTracesPanel, 'numeric');
            app.WhichTrajectoryToDisplayEditField.Limits = [1 Inf];
            app.WhichTrajectoryToDisplayEditField.ValueChangedFcn = createCallbackFcn(app, @WhichTrajectoryToDisplayEditFieldValueChanged, true);
            app.WhichTrajectoryToDisplayEditField.Value = 1;
            app.DisplayDropDownLabel = uilabel(app.ExploreAndFilterFLIMTracesPanel);
            app.DisplayDropDownLabel.Text = 'Display:';
            app.DisplayDropDown = uidropdown(app.ExploreAndFilterFLIMTracesPanel);
            app.DisplayDropDown.Items = {'Moving Median', 'Raw FLIM trace', 'Moving Averaged', 'Gaussian', 'Lowess', 'Loess', 'Robust Lowess', 'Robust Loess', 'Savitzky-Golay'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Moving Median';
            app.MovingWindowSizeEditFieldLabel = uilabel(app.ExploreAndFilterFLIMTracesPanel);
            app.MovingWindowSizeEditFieldLabel.Text = 'Moving Window Size';
            app.MovingWindowSizeEditField = uieditfield(app.ExploreAndFilterFLIMTracesPanel, 'numeric');
            app.MovingWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MovingWindowSizeEditFieldValueChanged, true);
            app.MovingWindowSizeEditField.Value = 3;
            app.FilterifneededanddisplayFLIMtracevstimeButton = uibutton(app.ExploreAndFilterFLIMTracesPanel, 'push');
            app.FilterifneededanddisplayFLIMtracevstimeButton.ButtonPushedFcn = createCallbackFcn(app, @FilterifneededanddisplayFLIMtracevstimeButtonPushed, true);
            app.FilterifneededanddisplayFLIMtracevstimeButton.Text = 'Filter (if needed) and display FLIM trace vs time';
            app.SaveFLIMFilteredTracesButton = uibutton(app.ExploreAndFilterFLIMTracesPanel, 'push');
            app.SaveFLIMFilteredTracesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFLIMFilteredTracesButtonPushed, true);
            app.SaveFLIMFilteredTracesButton.Text = 'Save FLIM Filtered Traces';
            app.ConfidenceRangeLifetimeEditFieldLabel = uilabel(app.ExploreAndFilterFLIMTracesPanel);
            app.ConfidenceRangeLifetimeEditFieldLabel.Text = 'Confidence Range Lifetime';
            app.ConfidenceRangeLifetimeEditField = uieditfield(app.ExploreAndFilterFLIMTracesPanel, 'numeric');
            app.ConfidenceRangeLifetimeEditField.Limits = [0 Inf];
            app.ConfidenceRangeLifetimeEditField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceRangeLifetimeEditFieldValueChanged, true);
            app.ConfidenceRangeLifetimeEditField.Value = 0.3;
            app.DropDown = uidropdown(app.ExploreAndFilterFLIMTracesPanel);
            app.DropDown.Items = {'>=', '<'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '>=';
            app.DefineCellContourandCentrePanel = uipanel(app.UIFigure);
            app.DefineCellContourandCentrePanel.Title = ' Define Cell Contour and Centre';
            app.MorphologicalOperationsonBinaryDropDown = uidropdown(app.DefineCellContourandCentrePanel);
            app.MorphologicalOperationsonBinaryDropDown.Items = {'clean', 'clean + open', 'clean+close'};
            app.MorphologicalOperationsonBinaryDropDown.ValueChangedFcn = createCallbackFcn(app, @MorphologicalOperationsonBinaryDropDownValueChanged, true);
            app.MorphologicalOperationsonBinaryDropDown.Value = 'clean+close';
            app.PerformMorphologicalOperationsButton = uibutton(app.DefineCellContourandCentrePanel, 'push');
            app.PerformMorphologicalOperationsButton.ButtonPushedFcn = createCallbackFcn(app, @PerformMorphologicalOperationsButtonPushed, true);
            app.PerformMorphologicalOperationsButton.Text = 'Perform Morphological Operations';
            app.ImageToUsetoDefineCellShapeDropDownLabel = uilabel(app.DefineCellContourandCentrePanel);
            app.ImageToUsetoDefineCellShapeDropDownLabel.Text = 'Image To Use to Define Cell Shape';
            app.ImageToUsetoDefineCellShapeDropDown = uidropdown(app.DefineCellContourandCentrePanel);
            app.ImageToUsetoDefineCellShapeDropDown.Items = {'Temporal Average', 'Temporal Max Intensity', 'Temporal Median'};
            app.ImageToUsetoDefineCellShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageToUsetoDefineCellShapeDropDownValueChanged, true);
            app.ImageToUsetoDefineCellShapeDropDown.Value = 'Temporal Average';
            app.ThresholdCellShapeSliderLabel = uilabel(app.DefineCellContourandCentrePanel);
            app.ThresholdCellShapeSliderLabel.Text = 'Threshold Cell Shape';
            app.ThresholdCellShapeSlider = uislider(app.DefineCellContourandCentrePanel);
            app.ThresholdCellShapeSlider.Limits = [0 10];
            app.ThresholdCellShapeSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdCellShapeSliderValueChanged, true);
            app.ThresholdCellShapeSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdCellShapeSliderValueChanging, true);
            app.ThresholdCellShapeSlider.Value = 1;
            app.ExtractCellContourandCenterCoordinatesButton = uibutton(app.DefineCellContourandCentrePanel, 'push');
            app.ExtractCellContourandCenterCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractCellContourandCenterCoordinatesButtonPushed, true);
            app.ExtractCellContourandCenterCoordinatesButton.Text = 'Extract Cell Contour and Center Coordinates';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubcellularTrackingWithFLIMCharacterisationV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end